classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        StatusLabel              matlab.ui.control.Label
        GenerateButton           matlab.ui.control.Button
        PhaseEditField           matlab.ui.control.NumericEditField
        PhaseEditFieldLabel      matlab.ui.control.Label
        FrequencyEditField       matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
        AmplitudeEditField       matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel  matlab.ui.control.Label
        WaveformDropDown         matlab.ui.control.DropDown
        WaveformDropDownLabel    matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            tipVal  = app.WaveformDropDown.Value;
            ampVal  = app.AmplitudeEditField.Value;
            freqVal = app.FrequencyEditField.Value;
            fazVal  = app.PhaseEditField.Value;

            [t, y] = m_waveform(tipVal, ampVal, freqVal, fazVal);

            plot(app.UIAxes, t, y, 'LineWidth', 1.5, 'Color', [0 0.447 0.741]);

            grid(app.UIAxes, 'on');
            ylabel(app.UIAxes, 'Amplitude');
            xlabel(app.UIAxes, 'Time (s)');
            title(app.UIAxes, ['Signal: ', tipVal]);

            if strcmpi(tipVal, 'Sinc Pulse')
                 app.UIAxes.XLim = [2.5 - (5/freqVal), 2.5 + (5/freqVal)];
            elseif strcmpi(tipVal, 'Chirp')
                 app.UIAxes.XLim = [0, 10/freqVal];
            elseif strcmpi(tipVal, 'Noise')
                app.UIAxes.XLim = [0, 0.005];
            else
                 app.UIAxes.XLim = [0, 5/freqVal];
            end
    
            app.UIAxes.YLim = [-ampVal*1.2, ampVal*1.2];

            filename = sprintf('%s_Amp%.2f_Freq%d_Phase%.2f.wav', tipVal,ampVal,freqVal,fazVal);
            audiowrite(filename,y,44100);
            app.StatusLabel.Text = ['File saved succesfully: ', filename];
            app.StatusLabel.FontColor = [0 0.5 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.WaveformDropDownLabel = uilabel(app.UIFigure);
            app.WaveformDropDownLabel.Text = 'Waveform';
            app.WaveformDropDown = uidropdown(app.UIFigure);
            app.WaveformDropDown.Items = {'Sin', 'Square', 'Sawtooth', 'Triangle', 'Noise', 'Rectangular', 'Sinc Pulse', 'Chirp', 'Pulse', 'Dc'};
            app.WaveformDropDown.Value = 'Sin';
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end